classdef Login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        LoginTab                       matlab.ui.container.Tab
        GridLayout2_2                  matlab.ui.container.GridLayout
        ProjectFileSelector            wt.FileSelector
        ProjectFileLabel               matlab.ui.control.Label
        LoginButton                    matlab.ui.control.Button
        PasswordLoginField             matlab.ui.control.EditField
        PasswordEditField_2Label       matlab.ui.control.Label
        LoginLabel                     matlab.ui.control.Label
        RegisterTab                    matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        ProjectFileFolderSelector      wt.FileSelector
        RegisterButton                 matlab.ui.control.Button
        ProjectFileLocationLabel       matlab.ui.control.Label
        PasswordReenterField           matlab.ui.control.EditField
        ReenterPasswordEditFieldLabel  matlab.ui.control.Label
        PasswordRegisterField          matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        ProjectNameRegisterEditField   matlab.ui.control.EditField
        ProjectNameEditFieldLabel      matlab.ui.control.Label
        RegisterLabel                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function warn(app, message)
            uialert( ...
                app.UIFigure, ...
                message, ...
                'Warning', ...
                'Icon', 'warning');
        end
        
        function inform(app, message)
            uialert( ...
                app.UIFigure, ...
                message, ...
                'Info', ...
                'Icon', 'info');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            projectName = app.ProjectNameRegisterEditField.Value;
            password = app.PasswordRegisterField.Value;
            passwordAgain = app.PasswordReenterField.Value;
            projFileLocation = app.ProjectFileFolderSelector.FullPath;

            if ~strcmp(password, passwordAgain)
                app.warn("Your passwords don't match");
                return;
            end

            regex = '[a-zA-Z0-9_]+';
            projectNameValid = matches(projectName, regexpPattern(regex));

            if ~projectNameValid || password == ""
                app.warn( ...
                    "Your project name or password is invalid. " + ...
                    "Project names can only contain a-z, A-Z, and _." + ...
                    "Passwords must not be empty.")
                return;
            end

            [folder, status] = GetWritableFolder('ForceStatus', 1);

            if status == 0
                app.warn("Could not save data on your system")
                return;
            end

            d = uiprogressdlg(app.UIFigure,'Title','Creating folders...', ...
                    'Indeterminate','on');

            projFolderName = fullfile(folder, projectName);
            if exist(projFolderName, 'dir')
                app.warn("A project with this name has already been saved on your system. " + ...
                    "Delete it or choose a new project name");
                close(d);
                return;
            end

            try 
                annotFolderName = fullfile(projFolderName, "annotations");
                dataFolderName = fullfile(projFolderName, "data");

                mkdir(annotFolderName);
                mkdir(dataFolderName);

                projInfo = struct( ...
                    'project_name', projectName, ...
                    'annot_folder', annotFolderName, ...
                    'data_folder', dataFolderName);

                projInfoAsJson = jsonencode(projInfo, PrettyPrint=true);
                projFileName = fullfile(projFileLocation, strcat(projectName, ".json"));

                writelines(projInfoAsJson, projFileName);
            catch
                app.warn("Failed to create folders and project file");
                close(d);
                return;
            end


            d.Title = "Contacting server...";

            try
                [~, success] = httpManager.register(app.serverUrl, projectName, password, "Generated project");
            catch
                app.warn("Could not connect to server");
                return;
            end

            if ~success
                warning = strcat( ...
                    "Registration was unsuccessful. ", ...
                    "Your project name might be taken");
                app.warn(warning);
                return;
            end

            close(d);

            app.inform("Registration was successful");
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            projectFile = app.ProjectFileSelector.Value;
            password = app.PasswordLoginField.Value;
            
            try
                projectFileContents = jsondecode(fileread(projectFile));
                projectName = projectFileContents.project_name;
                annotFolder = projectFileContents.annot_folder;
                dataFolder = projectFileContents.data_folder;
            catch
                app.warn("Could not read project file contents")
                return;
            end

            d = uiprogressdlg(app.UIFigure,'Title','Contacting server...',...
                    'Indeterminate','on');
            try
                [requestManager, success] = httpManager.fromLogin(app.serverUrl, projectName, password);
            catch ME
                disp(ME)
                app.warn("Could not connect to server");
                return;
            end

            close(d);

            if success
                Menu(projectName, requestManager, annotFolder, dataFolder);
                delete(app);
            else
                app.warn("Login unsuccessful. Check your connection or credentials")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.LoginTab = uitab(app.TabGroup);
            app.LoginTab.Title = 'Login';
            app.GridLayout2_2 = uigridlayout(app.LoginTab);
            app.LoginLabel = uilabel(app.GridLayout2_2);
            app.LoginLabel.Text = 'Login';
            app.PasswordEditField_2Label = uilabel(app.GridLayout2_2);
            app.PasswordEditField_2Label.Text = 'Password';
            app.PasswordLoginField = uieditfield(app.GridLayout2_2, 'text');
            app.LoginButton = uibutton(app.GridLayout2_2, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.ProjectFileLabel = uilabel(app.GridLayout2_2);
            app.ProjectFileLabel.Text = 'Project File';
            app.ProjectFileSelector = wt.FileSelector(app.GridLayout2_2);
            app.ProjectFileSelector.Filter = "*.json";
            app.RegisterTab = uitab(app.TabGroup);
            app.RegisterTab.Title = 'Register';
            app.GridLayout2 = uigridlayout(app.RegisterTab);
            app.RegisterLabel = uilabel(app.GridLayout2);
            app.RegisterLabel.Text = 'Register';
            app.ProjectNameEditFieldLabel = uilabel(app.GridLayout2);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameRegisterEditField = uieditfield(app.GridLayout2, 'text');
            app.PasswordEditFieldLabel = uilabel(app.GridLayout2);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordRegisterField = uieditfield(app.GridLayout2, 'text');
            app.ReenterPasswordEditFieldLabel = uilabel(app.GridLayout2);
            app.ReenterPasswordEditFieldLabel.Text = 'Re-enter Password';
            app.PasswordReenterField = uieditfield(app.GridLayout2, 'text');
            app.ProjectFileLocationLabel = uilabel(app.GridLayout2);
            app.ProjectFileLocationLabel.Text = 'Project File Location';
            app.RegisterButton = uibutton(app.GridLayout2, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'Register';
            app.ProjectFileFolderSelector = wt.FileSelector(app.GridLayout2);
            app.ProjectFileFolderSelector.SelectionType = 'folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end